classdef task2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ResizeTab                   matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        SelectInputFolderButton     matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        SelectOutputFolderButton    matlab.ui.control.Button
        Label_2                     matlab.ui.control.Label
        FiletypeDropDownLabel       matlab.ui.control.Label
        FiletypeDropDown            matlab.ui.control.DropDown
        ResizeButton                matlab.ui.control.Button
        Label_3                     matlab.ui.control.Label
        TrainTab                    matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        SelectSourceFolderButton    matlab.ui.control.Button
        Label_4                     matlab.ui.control.Label
        StartTrainingButton         matlab.ui.control.Button
        CustomdatasetnameEditFieldLabel  matlab.ui.control.Label
        CustomdatasetnameEditField  matlab.ui.control.EditField
        TestTab                     matlab.ui.container.Tab
        LoadImageButton             matlab.ui.control.Button
        LoadNetworkButton           matlab.ui.control.Button
        ImageAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        inputfolder
        outputfolder
        inputcontent
        inputcount
        trainingsource
        loadimage
        im
        net
        % Description
    end
    
    methods (Access = public)
        
        function updateimage(app, imagefile)
            if strcmp(imagefile,'corn.tif')
                app.im = imread('corn.tif', 2);
            else
                try
                    app.im = imread(imagefile);
                catch
                    try
                        app.im=imagefile;
                    catch ME
                        % If problem reading image, display error message
                        uialert(app.UIFigure, ME.message, 'Image Error');
                        return;
                    end
                end
            end
        end
        
        function classifyimg(app)
            %sz = app.net.Layers(1).InputSize;
            app.im=imresize(app.im,[227 227]);
            %label = classify(app.net,app.im);
            [label,score] = classify(app.net,app.im);
            app.ImageAxes.Title.String=(string(label) + ", " + num2str(100*max(score(1,:)),3) + "%");
            %app.ImageAxes.Title.String=label;
            %title(label)
        end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ResizeButton.Enable=false;
            app.StartTrainingButton.Enable=false;
            app.LoadImageButton.Enable=false;
        end

        % Button pushed function: SelectInputFolderButton
        function SelectInputFolderButtonPushed(app, event)
            app.inputfolder=strcat(uigetdir(),'\');
            app.Label.Text=app.inputfolder;
            if strlength(app.Label.Text)>0 && strlength(app.Label_2.Text)>0
                app.ResizeButton.Enable=true;
            end
        end

        % Button pushed function: SelectOutputFolderButton
        function SelectOutputFolderButtonPushed(app, event)
            app.outputfolder=strcat(uigetdir(),'\');
            app.Label_2.Text=app.outputfolder;
            app.inputcontent = dir ([app.inputfolder,'*',app.FiletypeDropDown.Value]);                 
            app.inputcount = size (app.inputcontent,1);
            restxt=strcat('Resize (',int2str(app.inputcount));
            restxt=strcat(restxt,')');
            app.ResizeButton.Text=restxt;
            if strlength(app.Label.Text)>0 && strlength(app.Label_2.Text)>0
                app.ResizeButton.Enable=true;
            end
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
              %Klasördeki dosya_sayisi kadar
            for k=1:app.inputcount
               dosya_adi = [app.inputfolder,app.inputcontent(k,1).name]; %Dosyayý adýný al
               I=imread(dosya_adi);%Aldýðýn dosyayý oku
               J=imresize(I,[227 227]);%%% Yada sizin derin öðrenme giriþi ne ise
               if mod(k,randi([1 10],1))==0
                   switch randi([2 5],1)
                       case 2
                           J=histeq(J);
                       case 3
                           J=J+randi([-10 10],1);
                       case 4
                           J=J*randi([1 4],1);
                       case 5
                           J=J/randi([1 4],1);
                   end  
               end    
               [filepath,name,ext] = fileparts(dosya_adi);
               ext='.png';
               name=fullfile(app.outputfolder, strcat(name,ext));
               imwrite(J,name);
            end
            app.Label_3.Text=strcat(int2str(app.inputcount),' images resized!');
        end

        % Button pushed function: SelectSourceFolderButton
        function SelectSourceFolderButtonPushed(app, event)

            app.trainingsource=uigetdir();
            app.Label_4.Text=app.trainingsource;
            if strlength(app.Label_4.Text)>0
                app.StartTrainingButton.Enable=true;
            end
        end

        % Button pushed function: StartTrainingButton
        function StartTrainingButtonPushed(app, event)
            alex=alexnet;% Bu çalýþmada  Matlab'ýn önceden eðitilmiþ aðýný (Alexnet) kullanýyoruz
            layers=alex.Layers;%Að katmanlarý Alexnetin katmanlarý
            layers(23)=fullyConnectedLayer(length(dir(app.trainingsource))-2);%% Grup 4 kiþi ise
            layers(25)=classificationLayer;%Son katman sýnýflandýrma katmaný
            allImages=imageDatastore(app.trainingsource,'IncludeSubfolders',true,'LabelSource','foldernames');
            %% Önemli Klasör isimleri yapay zekanýn çýkýþ etiketleri olacak
            %resimleri bu etiketlerden birine göre sýnýflandýracak. 
            [trainingImages,testImages]= splitEachLabel(allImages,0.8,'randomized');%Tüm 
            %Verilerin %80 ni eðitim %20 sini test için kullanýyor.
            %Bu oraný deðiþtirerek að baþarýsýný deðiþtirebilirsiniz.
            options = trainingOptions('sgdm', ...
                'MiniBatchSize',10, ...
                'MaxEpochs',6, ...
                'InitialLearnRate',1e-4, ...
                'Plots','training-progress');%Burasý aðýn öðrenmesi ile ilgili
            mynet = trainNetwork(trainingImages,layers,options);%Burda að eðitiliyor
            namestr=app.CustomdatasetnameEditField.Value;
            save(namestr, 'mynet');
            app.StartTrainingButton.Text='Saved!';
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
             % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                updateimage(app, fname);
                imshow(app.im,'Parent',app.ImageAxes);
                classifyimg(app);
            end
        end

        % Button pushed function: LoadNetworkButton
        function LoadNetworkButtonPushed(app, event)

            filterspec = {'*.mat','Mat Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                a = load(fname);
                z = fieldnames(a);           % gets you a cell with a's fieldname
                z = z{1};                    % in my case, the network is in the first cell field
                app.net = a.(z);
                app.LoadImageButton.Enable=true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ResizeTab = uitab(app.TabGroup);
            app.ResizeTab.Title = 'Resize';
            app.GridLayout = uigridlayout(app.ResizeTab);
            app.SelectInputFolderButton = uibutton(app.GridLayout, 'push');
            app.SelectInputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputFolderButtonPushed, true);
            app.SelectInputFolderButton.Text = 'Select Input Folder';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.SelectOutputFolderButton = uibutton(app.GridLayout, 'push');
            app.SelectOutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFolderButtonPushed, true);
            app.SelectOutputFolderButton.Text = 'Select Output Folder';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.FiletypeDropDownLabel = uilabel(app.GridLayout);
            app.FiletypeDropDownLabel.Text = 'File type:';
            app.FiletypeDropDown = uidropdown(app.GridLayout);
            app.FiletypeDropDown.Items = {'.jpg', '.jpeg', '.png', '.bmp'};
            app.FiletypeDropDown.Value = '.jpg';
            app.ResizeButton = uibutton(app.GridLayout, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '';
            app.TrainTab = uitab(app.TabGroup);
            app.TrainTab.Title = 'Train';
            app.GridLayout2 = uigridlayout(app.TrainTab);
            app.SelectSourceFolderButton = uibutton(app.GridLayout2, 'push');
            app.SelectSourceFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSourceFolderButtonPushed, true);
            app.SelectSourceFolderButton.Text = 'Select Source Folder';
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = '';
            app.StartTrainingButton = uibutton(app.GridLayout2, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingButtonPushed, true);
            app.StartTrainingButton.Text = 'Start Training';
            app.CustomdatasetnameEditFieldLabel = uilabel(app.GridLayout2);
            app.CustomdatasetnameEditFieldLabel.Text = 'Custom dataset name: ';
            app.CustomdatasetnameEditField = uieditfield(app.GridLayout2, 'text');
            app.CustomdatasetnameEditField.Value = 'dataset';
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.LoadImageButton = uibutton(app.TestTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.LoadNetworkButton = uibutton(app.TestTab, 'push');
            app.LoadNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNetworkButtonPushed, true);
            app.LoadNetworkButton.Text = 'Load Network';
            app.ImageAxes = uiaxes(app.TestTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end